classdef nwbsave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        RelatedPublications             matlab.ui.control.EditField
        RelatedPublicationsLabel        matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Hyperlink                       matlab.ui.control.Hyperlink
        OpenDANDIaftersavingCheckBox    matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        NWBCompatibleDataLabel          matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        NeuroPALImageNode               matlab.ui.container.TreeNode
        NeuronIDsNode                   matlab.ui.container.TreeNode
        NeuronActivityNode              matlab.ui.container.TreeNode
        NeuronSegmentationsNode         matlab.ui.container.TreeNode
        GCaMPVideoNode                  matlab.ui.container.TreeNode
        GCaMPAnnotationsNode            matlab.ui.container.TreeNode
        DatadescriptionTextArea         matlab.ui.control.TextArea
        DatadescriptionTextAreaLabel    matlab.ui.control.Label
        InstitutionalAffiliationEditField  matlab.ui.control.EditField
        InstitutionalAffiliationEditFieldLabel  matlab.ui.control.Label
        AuthorLaboratoryEditField       matlab.ui.control.EditField
        AuthorLaboratoryEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        parent_app;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, id_bool, csv_bool, gcamp_bool, activity_bool, annotation_bool, segmentation_bool)
            app.parent_app = parent_app;
            
            app.Tree.CheckedNodes

            if ~strcmp(id_bool,'False')
                app.Tree.CheckedNodes(end+1) = app.NeuronIDsNode;
            end

            if ~strcmp(gcamp_bool,'False')
                app.Tree.CheckedNodes(end+1) = app.GCaMPVideoNode;
            end

            if ~strcmp(activity_bool,'False')
                app.Tree.CheckedNodes(end+1) = app.NeuronActivityNode;
            end

            if ~strcmp(annotation_bool,'False')
                app.Tree.CheckedNodes(end+1) = app.GCaMPAnnotationsNode;
            end

            if ~strcmp(segmentation_bool,'False')
                app.Tree.CheckedNodes(end+1) = app.NeuronSegmentationsNode;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            nwb_data = [];
            nwb_data.OpenDandi = app.OpenDANDIaftersavingCheckBox.Value;
            nwb_data.author = app.AuthorLaboratoryEditField.Value;
            nwb_data.institution = app.InstitutionalAffiliationEditField.Value;
            nwb_data.description = app.DatadescriptionTextArea.Value;
            nwb_data.related_pubs = app.RelatedPublications.Value;
            nwb_data.proceed = 1;
            app.parent_app.process_nwb_data(nwb_data);
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            nwb_data = [];
            nwb_data.proceed = 0;
            app.parent_app.process_nwb_data(nwb_data);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AuthorLaboratoryEditFieldLabel = uilabel(app.GridLayout);
            app.AuthorLaboratoryEditFieldLabel.Text = 'Author / Laboratory';
            app.AuthorLaboratoryEditField = uieditfield(app.GridLayout, 'text');
            app.AuthorLaboratoryEditField.Value = 'N/A';
            app.InstitutionalAffiliationEditFieldLabel = uilabel(app.GridLayout);
            app.InstitutionalAffiliationEditFieldLabel.Text = 'Institutional Affiliation';
            app.InstitutionalAffiliationEditField = uieditfield(app.GridLayout, 'text');
            app.InstitutionalAffiliationEditField.Value = 'N/A';
            app.DatadescriptionTextAreaLabel = uilabel(app.GridLayout);
            app.DatadescriptionTextAreaLabel.Text = 'Data description';
            app.DatadescriptionTextArea = uitextarea(app.GridLayout);
            app.DatadescriptionTextArea.Value = {'N/A'};
            app.Tree = uitree(app.GridLayout, 'checkbox');
            app.Tree.Enable = 'off';
            app.NeuroPALImageNode = uitreenode(app.Tree);
            app.NeuroPALImageNode.Text = 'NeuroPAL Image';
            app.NeuronIDsNode = uitreenode(app.Tree);
            app.NeuronIDsNode.Text = 'Neuron IDs';
            app.NeuronActivityNode = uitreenode(app.Tree);
            app.NeuronActivityNode.Text = 'Neuron Activity';
            app.NeuronSegmentationsNode = uitreenode(app.Tree);
            app.NeuronSegmentationsNode.Text = 'Neuron Segmentations';
            app.GCaMPVideoNode = uitreenode(app.Tree);
            app.GCaMPVideoNode.Text = 'GCaMP Video';
            app.GCaMPAnnotationsNode = uitreenode(app.Tree);
            app.GCaMPAnnotationsNode.Text = 'GCaMP Annotations';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.NeuroPALImageNode];
            app.NWBCompatibleDataLabel = uilabel(app.GridLayout);
            app.NWBCompatibleDataLabel.Text = 'NWB-Compatible Data';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.OpenDANDIaftersavingCheckBox = uicheckbox(app.GridLayout3);
            app.OpenDANDIaftersavingCheckBox.Text = 'Open DANDI after saving.';
            app.Hyperlink = uihyperlink(app.GridLayout3);
            app.Hyperlink.URL = 'https://www.dandiarchive.org/';
            app.Hyperlink.Text = '(?)';
            app.RelatedPublicationsLabel = uilabel(app.GridLayout);
            app.RelatedPublicationsLabel.Text = 'Related Publications';
            app.RelatedPublications = uieditfield(app.GridLayout, 'text');
            app.RelatedPublications.Value = 'N/A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nwbsave(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end