classdef activity_browser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FileMenu            matlab.ui.container.Menu
        LoadMenu            matlab.ui.container.Menu
        ImportActivityMenu  matlab.ui.container.Menu
        ExportPlotsMenu     matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        GridLayout2         matlab.ui.container.GridLayout
        Tree                matlab.ui.container.CheckBoxTree
        ShiftclicktoselectrangeCTRLclicktomultiselectLabel  matlab.ui.control.Label
        ActivityTrace       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        parent_app; % Description
        neuron_info;
        neuron_activity_by_name;
    end
    
    methods (Access = private)

        function addNode(app, parent, name)
                if ~any(strcmp(keys(app.neuron_activity_by_name),name))
                    outputStr = '';
                    for i = 1:(length(name)-1)
                        outputStr = [outputStr, name(i), '̶']; % Pseudo strikethrough.
                    end
                    name = outputStr;
                    data = 0;
                else
                    data = 1;
                end

                uitreenode(parent,'text', name, 'NodeData', data);
        end

        function init_tree(app)
            for n=1:size(app.Tree.Children)
                node = app.Tree.Children(n);
                switch node.Text
                    case 'Pharynx'
                        for i=1:size(node.Children)
                            kiddo = node.Children(i);
                            switch kiddo.Text
                                case 'Anterior'
                                    neurons = app.neuron_info.getAnteriorPharynx();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Posterior'
                                    neurons = app.neuron_info.getPosteriorPharynx();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                            end
                        end
                    case 'Head'
                        for i=1:size(node.Children)
                            kiddo = node.Children(i);
                            switch kiddo.Text
                                case 'Anterior (L)'
                                    neurons = app.neuron_info.getLeftAnteriorGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Anterior (R)'
                                    neurons = app.neuron_info.getRightAnteriorGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Dorsal'
                                    neurons = app.neuron_info.getDorsalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Lateral (L)'
                                    neurons = app.neuron_info.getLeftLateralGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Lateral (R)'
                                    neurons = app.neuron_info.getRightLateralGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Ventral'
                                    neurons = app.neuron_info.getVentralGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Retro-Vesicular'
                                    neurons = app.neuron_info.getRetroVesicularGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                            end
                        end
                    case 'Midbody'
                        for i=1:size(node.Children)
                            kiddo = node.Children(i);
                            switch kiddo.Text
                                case 'Anterior'
                                    neurons = app.neuron_info.getAnteriorMidbody();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Central'
                                    neurons = app.neuron_info.getCentralMidbody();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Posterior'
                                    neurons = app.neuron_info.getPosteriorMidbody();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Ventral Cord'
                                    neurons = app.neuron_info.getVentralNerveCord();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                            end
                        end
                    case 'Tail'
                        for i=1:size(node.Children)
                            kiddo = node.Children(i);
                            switch kiddo.Text
                                case 'Pre-Anal'
                                    neurons = app.neuron_info.getPreAnalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Dorso-Rectal'
                                    neurons = app.neuron_info.getDorsoRectalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Lumbar (L)'
                                    neurons = app.neuron_info.getLeftLumbarGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Lumbar (R)'
                                    neurons = app.neuron_info.getRightLumbarGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                            end
                        end
                    case 'Male Tail'
                        for i=1:size(node.Children)
                            kiddo = node.Children(i);
                            switch kiddo.Text
                                case 'Pre-Anal (L)'
                                    neurons = app.neuron_info.getLeftPreAnalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Pre-Anal (R)'
                                    neurons = app.neuron_info.getRightPreAnalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Rays (L)'
                                    neurons = app.neuron_info.getLeftRays();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Rays (R)'
                                    neurons = app.neuron_info.getRightRays();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Dorso-Rectal'
                                    neurons = app.neuron_info.getDorsoRectalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Cloacal (L)'
                                    neurons = app.neuron_info.getLeftCloacalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Cloacal (R)'
                                    neurons = app.neuron_info.getRightCloacalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Lumbar (L)'
                                    neurons = app.neuron_info.getLeftLumbarGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Lumbar (R)'
                                    neurons = app.neuron_info.getRightLumbarGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                            end
                        end
                end
            end
        end
        
        function updateNeurons(app, plotNeurons)
            for n=1:size(plotNeurons)
                n_activity = app.neuron_activity_by_name(plotNeurons(n));
            end

            title = app.titleGen(plotNeurons);
        end

        function title = titleGen(app, neurons);
        end

        function AddNeuron(app,event)
            app.AddAxes(app.MainGrid, event.Value, event.Source.Layout.Row, event.Source.Layout.Column);
            delete(event.Source);
        end

        function AddAxes(app, parent, name, row, column)
            new_axes = uiaxes(parent);
            title(new_axes, name)
            xlabel(new_axes, 't')
            ylabel(new_axes, 'Ca+')
            zlabel(new_axes, 'Z')
            new_axes.Layout.Row = row;
            new_axes.Layout.Column = column;
        end

        function CreateNeuron(app, caller, event)
            % Create TL_Panel
            new_panel = uipanel(app.MainGrid);

            if grid_space(1) == max(size(app.MainGrid.ColumnWidth))
                new_panel.Layout.Column = grid_space(2)+1;
            else
                new_panel.Layout.Column = grid_space;
            end

            if grid_space(2) == max(size(app.MainGrid.RowHeight))
                new_panel.Layout.Row = grid_space(1)+1;
            end

            % Create TL_Grid
            new_grid = uigridlayout(app.TL_Panel);
            new_grid.ColumnWidth = {'1x'};
            new_grid.RowHeight = {'1x'};

            % Create TL_Button
            new_button = uibutton(app.TL_Grid, 'push');
            new_button.ButtonPushedFcn = createCallbackFcn(app, @AddNeuron, true);
            new_button.FontWeight = 'bold';
            new_button.Layout.Row = 1;
            new_button.Layout.Column = 1;
            new_button.Text = 'Add Neuron';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, gang_tree, neuron_act, neuron_info)

            d = uiprogressdlg(app.UIFigure,'Title','Initializing activity browser...',...
        'Indeterminate','on');
            app.parent_app = parent_app;
            app.neuron_info = neuron_info;
            app.neuron_activity_by_name = neuron_act;
            arrayfun(@(x,y) copyobj(flipud(x.Children),y),gang_tree,app.Tree)
            app.init_tree()
            close(d);

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes
            plotNeurons = [];

            for i = 1:length(checkedNodes)
                node = checkedNodes(i);
                if node.NodeData == 1
                    plotNeurons = [allNeurons, node.Text];
                end
            end

            app.updateNeurons(plotNeurons);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.ImportActivityMenu = uimenu(app.FileMenu);
            app.ImportActivityMenu.Text = 'Import Activity';
            app.ExportPlotsMenu = uimenu(app.FileMenu);
            app.ExportPlotsMenu.Text = 'Export Plots';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ActivityTrace = uiaxes(app.GridLayout);
            title(app.ActivityTrace, 'Select Neuron In Ganglion Directory')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ShiftclicktoselectrangeCTRLclicktomultiselectLabel = uilabel(app.GridLayout2);
            app.ShiftclicktoselectrangeCTRLclicktomultiselectLabel.Text = {'Shift-click to select range,'; 'CTRL-click to multi-select.'};
            app.Tree = uitree(app.GridLayout2, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = activity_browser(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end