classdef autoseg_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_7                         matlab.ui.container.Panel
        Slider                          matlab.ui.control.Slider
        PreviewImageLabel               matlab.ui.control.Label
        InformationPanel                matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        VolumesTab                      matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        Panel_6                         matlab.ui.container.Panel
        GutAutofluoCheckBox             matlab.ui.control.CheckBox
        LysoAutofluCheckBox             matlab.ui.control.CheckBox
        GFPCheckBox                     matlab.ui.control.CheckBox
        WhiteCheckBox                   matlab.ui.control.CheckBox
        BlueCheckBox                    matlab.ui.control.CheckBox
        GreenCheckBox                   matlab.ui.control.CheckBox
        RedCheckBox                     matlab.ui.control.CheckBox
        ColorChannelsLabel              matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        Panel_4                         matlab.ui.container.Panel
        OpenSegmentationFolderButton_2  matlab.ui.control.Button
        OpenDataFolderButton            matlab.ui.control.Button
        MaxProjectionButton             matlab.ui.control.StateButton
        zSlicesListBox                  matlab.ui.control.ListBox
        Panel_3                         matlab.ui.container.Panel
        zSlicesLabel                    matlab.ui.control.Label
        NeuronsTab                      matlab.ui.container.Tab
        PasstoMainButton                matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        SegCount                        matlab.ui.control.NumericEditField
        IdCount                         matlab.ui.control.NumericEditField
        SegmentedneuronssuccesfullyIDd75neuronsLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        CreditTab                       matlab.ui.container.Tab
        LaboratoryPageButton            matlab.ui.control.Button
        GithubRepositoryButton          matlab.ui.control.Button
        OriginalPaperButton             matlab.ui.control.Button
        CiteassuchLabel                 matlab.ui.control.Label
        Segmentationrelieson3DeeCellTrackeraLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CellFilterLabel                 matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        VoxelResolutionEditField        matlab.ui.control.NumericEditField
        MinimumSizeLabel                matlab.ui.control.Label
        AveragenoncellintensityEditField  matlab.ui.control.NumericEditField
        AveragenoncellintensityEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        RESETButton                     matlab.ui.control.Button
        GENERATEButton                  matlab.ui.control.Button
        SettingsHoveroverlabelsforexplanationButtonGroup  matlab.ui.container.ButtonGroup
        AdjHistogramButton              matlab.ui.control.Button
        BlackOutToolButton_3            matlab.ui.control.Button
        MeasureNoiseButton              matlab.ui.control.Button
        DisplayButtonGroup              matlab.ui.container.ButtonGroup
        AutoSegButton                   matlab.ui.control.RadioButton
        OriginalButton                  matlab.ui.control.RadioButton
        CellFilterButtonGroup           matlab.ui.container.ButtonGroup
        NeuronCountButton               matlab.ui.control.RadioButton
        MinimumSizeButton               matlab.ui.control.RadioButton
        NoiseFilterButtonGroup          matlab.ui.container.ButtonGroup
        ManualButton                    matlab.ui.control.RadioButton
        AutoButton                      matlab.ui.control.RadioButton
        OverlayAxes                     matlab.ui.control.UIAxes
        ImageAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        live_view; %
        parent_app; % Reference to main Neuropal window.
        image_data; % Passed from main
        image_neurons;
        repo_img;
        repo_view;
        image_gamma; % Passed from main
        image_view; % Passed from main
        avg_nci;
        z_siz;
        image_raw;
        image_array;
        image_scale;
        auto_neurons;
        red; % Color indices.
        green;
        blue;
        white;
        gfp;
        dic;
        full_path;
        data_path;
        seg_path;
    end

    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function UpdateFromChild(app, image_data, image_neurons,image_gamma)
            app.image_data = image_data;
            app.image_gamma = image_gamma;
            app.DrawImageData()
        end

        function CalcNCI(app)
            % Read in the image
            img = app.image_data;
            
            % Convert the image to a 1D array of intensities
            intensities = double(img(:));
            
            % Calculate the 90th percentile of intensities
            p95 = prctile(intensities, 95);

            app.AveragenoncellintensityEditField.Value = p95*100;
        end

        function DrawImageData(app)

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % Determine the color channel indices.
            red = str2double(app.parent_app.RDropDown.Value);
            green = str2double(app.parent_app.GDropDown.Value);
            blue = str2double(app.parent_app.BDropDown.Value);
            white = str2double(app.parent_app.WDropDown.Value);
            dic = str2double(app.parent_app.DICDropDown.Value);
            gfp = str2double(app.parent_app.GFPDropDown.Value);

            % Determine the channel=color assignments for displaying.
            color_indices = [red, green, blue];

            % Draw the 3 color channels.
            app.image_view = app.image_data(:,:,:,color_indices);

            % Add in the white channel.
            if app.parent_app.WCheckBox.Value % White

                % Compute the white channel.
                wchannel = app.image_data(:,:,:,white);

                % Adjust the gamma.
                W_i = app.parent_app.gamma_RGBW_DIC_GFP_index(4); % white channel gamma index
                if length(app.image_gamma) >= W_i && app.image_gamma(W_i) ~= 1
                    wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));
                end

                % Add the white channel.
                app.image_view = app.image_view + repmat(wchannel, [1,1,1,3]);
            end

            % Add in the DIC channel.
            if app.parent_app.DICCheckBox.Value % DIC

                % Compute the DIC channel.
                dic_channel = app.image_data(:,:,:,dic);

                % Adjust the gamma.
                DIC_i = app.parent_app.gamma_RGBW_DIC_GFP_index(5); % DIC channel gamma index
                if length(app.image_gamma) >= DIC_i && app.image_gamma(DIC_i) ~= 1
                    dic_channel = imadjustn(dic_channel,[],[], app.image_gamma(DIC_i));
                end

                % Add the DIC channel.
                app.image_view = app.image_view + repmat(dic_channel, [1,1,1,3]);
            end

            % Add in the GFP channel.
            if app.parent_app.GFPCheckBox.Value % GFP

                % Compute the GFP channel.
                gfp_color = Program.GUIPreferences.instance().GFP_color;
                gfp_channel = app.image_data(:,:,:,gfp);

                % Adjust the gamma.
                GFP_i = app.parent_app.gamma_RGBW_DIC_GFP_index(6); % GFP channel gamma index
                if length(app.image_gamma) >= GFP_i && app.image_gamma(GFP_i) ~= 1
                    gfp_channel = imadjustn(gfp_channel,[],[], app.image_gamma(GFP_i));
                end

                % Add the GFP channel.
                gfp_channel = repmat(gfp_channel, [1,1,1,3]);
                gfp_channel(:,:,:,~gfp_color) = 0;
                app.image_view = app.image_view + gfp_channel;
            end

            % Adjust the gamma.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            app.image_view = uint16(double(intmax('uint16')) * ...
                double(app.image_view)/double(max(app.image_view(:))));
            for c = 1:size(app.image_view, 4)
                if app.image_gamma(c) ~= 1
                    app.image_view(:,:,:,c) = ...
                        imadjustn(squeeze(app.image_view(:,:,:,c)),[],[], ...
                        app.image_gamma(c));
                end
            end

            app.image_view = double(app.image_view)/double(max(app.image_view(:)));
            image(app.ImageAxes, squeeze(max(app.image_view,[],3)));

            switch app.NoiseFilterButtonGroup.SelectedObject.Text
                case 'Auto'
                    app.CalcNCI()
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_data, image_neurons, image_view, image_gamma, image_res, red, green, blue, white, dic, gfp, fullpath)
            
            app.parent_app = parent_app;
            app.image_data = image_data;
            app.repo_img = image_data;
            app.image_neurons = image_neurons;
            app.image_view = image_view;
            app.repo_view = image_view;
            app.image_gamma = image_gamma;
            app.image_scale = image_res(3) / image_res(1);
            app.full_path = fullfile(fullpath,'seg_data')
            app.data_path = fullfile(app.full_path,'data')
            app.seg_path = fullfile(app.full_path,'auto_vol1')
            fullfile(fullpath,'seg_data')
            fullfile(app.full_path,'data')
            fullfile(app.full_path,'auto_vol1')


            app.red = str2double(red);
            app.green = str2double(green);
            app.blue = str2double(blue);
            app.white = str2double(white);
            app.dic = str2double(dic);
            app.gfp = str2double(gfp);

            C = {};

            for n = 1:size(app.image_data, 3)
                C{n} = ['Slice ' num2str(n)];
            end

            app.zSlicesListBox.Items = C;
            app.zSlicesListBox.ItemsData = 1:size(app.image_data,3);
            app.zSlicesListBox.Value = 1:size(app.image_data,3);

            %axis(app.ImageAxes, 'normal');
            image(app.ImageAxes, squeeze(max(app.image_view,[],3)));

            app.z_siz = size(app.image_data, 3);
            app.CalcNCI()
    
            app.Slider.Limits = [0,size(app.image_data, 3)];
            app.Slider.Value = size(app.image_data, 3)/2;

            app.VoxelResolutionEditField.Value = app.image_scale;
            
        end

        % Selection changed function: CellFilterButtonGroup
        function CellFilterButtonGroupSelectionChanged(app, event)
            switch app.CellFilterButtonGroup.SelectedObject.Text
                case 'Minimum Size'
                    app.CellFilterLabel.Text = 'Minimum Size:';
                case 'Neuron Count'
                    app.CellFilterLabel.Text = 'Expected Count:';
            end
        end

        % Selection changed function: NoiseFilterButtonGroup
        function NoiseFilterButtonGroupSelectionChanged(app, event)
            switch app.NoiseFilterButtonGroup.SelectedObject.Text
                case 'Auto'
                    set(app.AveragenoncellintensityEditField,'Editable','off')
                    app.CalcNCI()
                case 'Manual'
                    set(app.AveragenoncellintensityEditField,'Editable','on')
            end
        end

        % Button pushed function: OriginalPaperButton
        function OriginalPaperButtonPushed(app, event)
            web('https://elifesciences.org/articles/59187')
        end

        % Button pushed function: GithubRepositoryButton
        function GithubRepositoryButtonPushed(app, event)
            web('https://github.com/WenChentao/3DeeCellTracker')
        end

        % Button pushed function: LaboratoryPageButton
        function LaboratoryPageButtonPushed(app, event)
            web('https://en.kokimura-lab.org/')
        end

        % Button pushed function: AdjHistogramButton
        function AdjHistogramButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Loading histogram...',...
        'Indeterminate','on');
            histogram_window(app, app.image_data, app.image_neurons, app.image_view, app.image_gamma, Program.GUIPreferences.instance(), app.parent_app.RDropDown.Value, app.parent_app.GDropDown.Value, app.parent_app.BDropDown.Value, app.parent_app.WDropDown.Value, app.parent_app.DICDropDown.Value, app.parent_app.GFPDropDown.Value);
            close(d)
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            check = uiconfirm(app.UIFigure,'Depending on your computer, this may take a while. Are you sure you want to proceed?','Warning!','Options',{'Yes','No'},'DefaultOption','Yes');
            
            if strcmp(check,'Yes') & (size(app.zSlicesListBox.Value) < size(app.image_data,3))
                slice_warn = uiconfirm(app.UIFigure,'Not all slices are selected. Proceed?','Warning!','Options',{'Yes','Yes, with all slices selected.','No'},'DefaultOption','No');

                switch slice_warn
                    case 'Yes, with all slices selected.'
                        image(app.ImageAxes, squeeze(max(app.image_view,[],3)));
                        app.z_siz = size(app.image_data,3);
                        app.zSlicesListBox.Value = 1:size(app.image_data,3);
                        app.MaxProjectionButton.Value = 1;

                        for i = 1:size(app.auto_neurons, 1)
                            images.roi.Point(app.ImageAxes,'Position',[app.auto_neurons(i,2) app.auto_neurons(i,1)], 'Color',[0 1 0], 'MarkerSize',3, 'LineWidth',1, 'Label',sprintf('Neuron #%.0f',i), 'LabelVisible','hover');
                        end

                    case 'No'
                        check = 'No';
                end

            end
            
            switch check
                case 'Yes'

                    d = uiprogressdlg(app.UIFigure,'Title','Processing data...',...
        'Indeterminate','on');

                    dirs = {app.data_path, app.seg_path, fullfile(app.full_path,'track_information'), fullfile(app.full_path,'track_results_SingleMode'), fullfile(app.full_path,'anim')};

                    for n = 1:length(dirs)
                        directory = char(dirs(n));
                        if ~exist(directory, 'dir')
                            [status, msg, msgid] = mkdir(directory);
                            if status ~= 1
                                fprintf('Failed to create directory %s: %s\n', directory, msg);
                            end
                        end
                    end

                    files = dir(fullfile(app.data_path,'*.*'));  % Get a list of all files in the folder
                    for i = 1:length(files)  % Loop through each file
                        file = fullfile(app.data_path,files(i).name);  % Get the full path to the file
                        if ~files(i).isdir  % Only delete files, not directories
                            delete(file);  % Delete the file
                        end
                    end
                    
                    channel_list = struct('CheckBox',{app.RedCheckBox, app.GreenCheckBox, app.BlueCheckBox, app.WhiteCheckBox, app.GFPCheckBox},'Index',{1,2,3,4,5});
                    chan_array = [];
                    for i = 1:numel(channel_list)
                        if channel_list(i).CheckBox.Value == 1
                            chan_array = [chan_array channel_list(i).Index];
                        end
                    end

                    for n = min(app.zSlicesListBox.Value):max(app.zSlicesListBox.Value)
                        name = join([app.data_path, 'npal_','worm_','c1_',int2str(n-min(app.zSlicesListBox.Value)), '.tif']);
                        subdata = app.image_data(:,:,n,chan_array);
                        subdata = mean(subdata, 4);
                        imwrite(subdata, name, 'tif', 'WriteMode', 'overwrite');
                    end

                    close(d)

                    d = uiprogressdlg(app.UIFigure,'Title','Segmenting slices...',...
                                                'Indeterminate','on');

                    if ispc % Check if running on Windows
                        system([pwd,'/NeuroPAL_ID/External_Dependencies/3dct/CellTracker/windows/npal_seg.exe',app.full_path,sprintf(' %f %f %f', app.EditField.Value, app.VoxelResolutionEditField.Value, app.AveragenoncellintensityEditField.Value)]);
                    elseif ismac % Check if running on macOS
                        system([pwd,'/NeuroPAL_ID/External_Dependencies/3dct/CellTracker/macos/npal_seg',app.full_path,sprintf('  %f %f %f', app.EditField.Value, app.VoxelResolutionEditField.Value, app.AveragenoncellintensityEditField.Value)]);
                    else % Otherwise
                        disp('Operating system not supported')
                    end
                    
                    close(d)

                    d = uiprogressdlg(app.UIFigure,'Title','Loading neurons...',...
                            'Indeterminate','on');

                    % Load the neuron information from the CSV file
                    neuron_info = readmatrix(join([app.full_path, '/neuron_info.csv']));
                    
                    % Initialize empty array to store the triplets of numbers
                    app.auto_neurons = zeros(size(neuron_info, 1), 3);
                    
                    % Loop through each row and extract the coordinates
                    for i = 1:size(neuron_info, 1)
                        currentRow = neuron_info(i,:);
                        app.auto_neurons(i,:) = currentRow;
                        app.UITable.Data(i,:) = {i, '', '', sprintf('(%.0f, %.0f, %.0f)', currentRow(1), currentRow(2), currentRow(3))};
                        images.roi.Point(app.ImageAxes,'Position',[currentRow(2) currentRow(1)], 'Color',[0 1 0], 'MarkerSize',3, 'LineWidth',1, 'Label',sprintf('Neuron #%.0f',i), 'LabelVisible','hover');
                    end
                    
                    % Update the segmentation count in the app
                    app.SegCount.Value = size(neuron_info, 1);

                    %set(app.AutoSegButton,'Enable',"On")

                    close(d)

                    seg_open = uiconfirm(app.UIFigure,'Segmentation finished. Open results folder?','NeuroPAL','Options',{'Yes','No'},'DefaultOption','No');

                    switch seg_open
                        case 'Yes'
                            if ispc
                                winopen(app.seg_path);
                            elseif ismac
                                cmd = ['open ', app.seg_path];
                                system(cmd);
                            end
                    end

                case 'No'
                    return
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.image_data = app.repo_img;
            app.image_view = app.repo_view;
            app.UITable.Data = {};
            app.auto_neurons = [];
            app.SegCount.Value = 0;
            app.RedCheckBox.Value = 1;
            app.GreenCheckBox.Value = 1;
            app.BlueCheckBox.Value = 1;
            app.WhiteCheckBox.Value = 0;
            app.GFPCheckBox.Value = 0;
            app.LysoAutofluCheckBox.Value = 0;
            app.GutAutofluoCheckBox.Value = 0;
            app.Slider.Value = size(app.image_data, 3)/2;
            image(app.ImageAxes, squeeze(max(app.image_view,[],3)));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Clicked callback: zSlicesListBox
        function zSlicesListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.z_siz = max(app.zSlicesListBox.Value) - min(app.zSlicesListBox.Value);
            app.MaxProjectionButton.Value = 0;
        end

        % Value changed function: zSlicesListBox
        function zSlicesListBoxValueChanged(app, event)
            z_slice = max(app.zSlicesListBox.Value);
            app.Slider.Value = z_slice;
            delete(findobj(app.ImageAxes,'Type','images.roi.Point'))
            image(app.ImageAxes, squeeze(app.image_view(:,:,z_slice,:,:)));
            for i = 1:size(app.auto_neurons, 1)
                if round(app.auto_neurons(i,3),0) == z_slice | round(app.auto_neurons(i,3),0) == z_slice+1 | round(app.auto_neurons(i,3),0) == z_slice-1
                    images.roi.Point(app.ImageAxes,'Position',[app.auto_neurons(i,2) app.auto_neurons(i,1)], 'Color',[0 1 0], 'MarkerSize',3, 'LineWidth',1, 'Label',sprintf('Neuron #%.0f',i), 'LabelVisible','hover');
                end
            end
            app.MaxProjectionButton.Value = 0;
        end

        % Value changed function: MaxProjectionButton
        function MaxProjectionButtonValueChanged(app, event)
            if app.MaxProjectionButton.Value == 1
                app.Slider.Value = size(app.image_data, 3)/2;
                image(app.ImageAxes, squeeze(max(app.image_view,[],3)));
                app.z_siz = size(app.image_data,3);
                app.zSlicesListBox.Value = 1:size(app.image_data,3);
                for i = 1:size(app.auto_neurons, 1)
                    images.roi.Point(app.ImageAxes,'Position',[app.auto_neurons(i,2) app.auto_neurons(i,1)], 'Color',[0 1 0], 'MarkerSize',3, 'LineWidth',1, 'Label',sprintf('Neuron #%.0f',i), 'LabelVisible','hover');
                end
            else
                image(app.ImageAxes, squeeze(app.image_view(:,:,app.zSlicesListBox.Value,:,:)));
            end
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            switch app.DisplayButtonGroup.SelectedObject.Text
                case 'Original'
                    image(app.ImageAxes, squeeze(max(app.image_view,[],3)));
                    app.z_siz = size(app.image_data,3);
                    app.zSlicesListBox.Value = 1:size(app.image_data,3);
                    for i = 1:size(app.auto_neurons, 1)
                        images.roi.Point(app.ImageAxes,'Position',[app.auto_neurons(i,2) app.auto_neurons(i,1)], 'Color',[0 1 0], 'MarkerSize',3, 'LineWidth',1, 'Label',sprintf('Neuron #%.0f',i), 'LabelVisible','hover');
                    end
                case 'Overlay'
                    image(app.ImageAxes, squeeze(max(app.image_view,[],3)));
                    app.z_siz = size(app.image_data,3);
                    app.zSlicesListBox.Value = 1:size(app.image_data,3);
                    for i = 1:size(app.auto_neurons, 1)
                        images.roi.Point(app.ImageAxes,'Position',[app.auto_neurons(i,2) app.auto_neurons(i,1)], 'Color',[0 1 0], 'MarkerSize',3, 'LineWidth',1, 'Label',sprintf('Neuron #%.0f',i), 'LabelVisible','hover');
                    end
            end
        end

        % Button pushed function: OpenDataFolderButton
        function OpenDataFolderButtonPushed(app, event)
            if ispc
                winopen(app.data_path);
            elseif ismac
                cmd = ['open ', app.data_path];
                system(cmd);
            end
        end

        % Button pushed function: OpenSegmentationFolderButton_2
        function OpenSegmentationFolderButton_2Pushed(app, event)
            if ispc
                winopen(app.seg_path);
            elseif ismac
                cmd = ['open ', app.seg_path];
                system(cmd);
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            file_info = [app.data_path,'*.csv'];
            [name, path, ~] = uigetfile(file_info, 'Save neuron table.');
            writetable(app.UITable.Data,join([path,name]))
        end

        % Button pushed function: PasstoMainButton
        function PasstoMainButtonPushed(app, event)
            check = uiconfirm(app.UIFigure,'This will add all of the auto-detected neurons into the main volume. Are you sure?','Confirmation','Options',{'Yes','No'},'DefaultOption','No');

            switch check
                case 'Yes'
                    app.parent_app.ImpNeurons(app.auto_neurons);
                    delete(app)
                case 'No'
                    return
            end
        end

        % Value changed function: RedCheckBox
        function RedCheckBoxValueChanged(app, event)
            if app.RedCheckBox.Value == 1
                app.image_data(:,:,:,app.red) = app.repo_img(:,:,:,app.red);
                app.DrawImageData()
            else
                app.image_data(:,:,:,app.red) = zeros(size(app.repo_img(:,:,:,app.red)));
                app.DrawImageData()
            end
        end

        % Value changed function: GreenCheckBox
        function GreenCheckBoxValueChanged(app, event)
            if app.GreenCheckBox.Value == 1
                app.image_data(:,:,:,app.green) = app.repo_img(:,:,:,app.green);
                app.DrawImageData()
            else
                app.image_data(:,:,:,app.green) = zeros(size(app.repo_img(:,:,:,app.green)));
                app.DrawImageData()
            end
        end

        % Value changed function: BlueCheckBox
        function BlueCheckBoxValueChanged(app, event)
            if app.BlueCheckBox.Value == 1
                app.image_data(:,:,:,app.blue) = app.repo_img(:,:,:,app.blue);
                app.DrawImageData()
            else
                app.image_data(:,:,:,app.blue) = zeros(size(app.repo_img(:,:,:,app.blue)));
                app.DrawImageData()
            end
        end

        % Value changed function: WhiteCheckBox
        function WhiteCheckBoxValueChanged(app, event)
            if app.WhiteCheckBox.Value == 1
                app.image_data(:,:,:,app.white) = app.repo_img(:,:,:,app.white);
                app.DrawImageData()
            else
                app.image_data(:,:,:,app.white) = zeros(size(app.repo_img(:,:,:,app.white)));
                app.DrawImageData()
            end
        end

        % Value changed function: GFPCheckBox
        function GFPCheckBoxValueChanged(app, event)
            if app.GFPCheckBox.Value == 1
                app.image_data(:,:,:,app.gfp) = app.repo_img(:,:,:,app.gfp);
                app.DrawImageData()
            else
                app.image_data(:,:,:,app.gfp) = zeros(size(app.repo_img(:,:,:,app.gfp)));
                app.DrawImageData()
            end
        end

        % Value changed function: Slider
        function SliderValueChanged2(app, event)
            z_slice = min(max(round(app.Slider.Value,0),1), size(app.image_data,3));
            delete(findobj(app.ImageAxes,'Type','images.roi.Point'))
            image(app.ImageAxes, squeeze(app.image_view(:,:,z_slice,:,:)));
            for i = 1:size(app.auto_neurons, 1)
                if round(app.auto_neurons(i,3),0) == z_slice | round(app.auto_neurons(i,3),0) == z_slice+1 | round(app.auto_neurons(i,3),0) == z_slice-1
                    images.roi.Point(app.ImageAxes,'Position',[app.auto_neurons(i,2) app.auto_neurons(i,1)], 'Color',[0 1 0], 'MarkerSize',3, 'LineWidth',1, 'Label',sprintf('Neuron #%.0f',i), 'LabelVisible','hover');
                end
            end
            app.zSlicesListBox.Value = z_slice;
            app.MaxProjectionButton.Value = 0;
        end

        % Value changing function: Slider
        function SliderValueChanging2(app, event)
            z_slice = min(max(round(app.Slider.Value,0),1), size(app.image_data,3));
            delete(findobj(app.ImageAxes,'Type','images.roi.Point'))
            image(app.ImageAxes, squeeze(app.image_view(:,:,z_slice,:,:)));
            for i = 1:size(app.auto_neurons, 1)
                if round(app.auto_neurons(i,3),0) == z_slice | round(app.auto_neurons(i,3),0) == z_slice+1 | round(app.auto_neurons(i,3),0) == z_slice-1
                    images.roi.Point(app.ImageAxes,'Position',[app.auto_neurons(i,2) app.auto_neurons(i,1)], 'Color',[0 1 0], 'MarkerSize',3, 'LineWidth',1, 'Label',sprintf('Neuron #%.0f',i), 'LabelVisible','hover');
                end
            end
            app.zSlicesListBox.Value = z_slice;
            app.MaxProjectionButton.Value = 0;
        end

        % Button pushed function: BlackOutToolButton_3
        function BlackOutToolButton_3Pushed(app, event)
            check = uiconfirm(app.UIFigure,'There are two available black out modes. Regular black out allows you to select a region to be nulled out before segmentation. Inverse mode allows you to select a region to be segmented.','Select Mode','Options',{'Regular Mode','Inverse Mode'},'DefaultOption','Regular Mode');

            uiwait(msgbox('Click and drag your cursor on the image to select the region to ignore during segmentation.','Instructions'))
            
            roi = drawfreehand(app.ImageAxes,'Color','black','StripeColor','m');
            mask = createMask(roi,app.image_view);

            switch check
                case 'Regular Mode'
                    mask = ~mask;
                    mask = double(mask);
        
                case 'Inverse Mode'
                    mask = double(mask);
            end
        
            for n = 1:size(app.image_data,3)
                color_indices = [1, 2, 3];
                app.image_data(:,:,:,color_indices) = double(app.image_data(:,:,:,color_indices)).*mask;
            end
    
            image(app.ImageAxes, squeeze(max(app.image_view,[],3)).*mask);
        end

        % Button pushed function: MeasureNoiseButton
        function MeasureNoiseButtonPushed(app, event)
            uiwait(msgbox('Click and drag your cursor on the image to select a region and calculate its average intensity.','Instructions'))
            
            roi = drawfreehand(app.ImageAxes,'Color','black','StripeColor','m');
            mask = createMask(roi,app.image_view);

            channel_list = struct('CheckBox',{app.RedCheckBox, app.GreenCheckBox, app.BlueCheckBox, app.WhiteCheckBox, app.GFPCheckBox},'Index',{1,2,3,4,5});
            chan_array = [];
            slice_avg = [];

            for i = 1:numel(channel_list)
                if channel_list(i).CheckBox.Value == 1
                    chan_array = [chan_array channel_list(i).Index];
                end
            end

            for n = min(app.zSlicesListBox.Value):max(app.zSlicesListBox.Value)
                name = join([app.data_path,'npal_','worm_','c1_',int2str(n-min(app.zSlicesListBox.Value)), '.tif']);
                subdata = app.image_data(:,:,n,chan_array);
                subdata = mean(subdata, 4);
                slice_avg = [slice_avg mean(subdata(mask))*100];
                imwrite(subdata, name, 'tif', 'WriteMode', 'overwrite');
            end

            % Get the average intensity of pixels within the ROI
            app.AveragenoncellintensityEditField.Value = mean(slice_avg);
            delete(roi)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.OverlayAxes = uiaxes(app.UIFigure);
            app.OverlayAxes.XTick = [];
            app.OverlayAxes.XTickLabel = '';
            app.OverlayAxes.YTick = [];
            app.OverlayAxes.Visible = 'off';
            app.SettingsHoveroverlabelsforexplanationButtonGroup = uibuttongroup(app.UIFigure);
            app.SettingsHoveroverlabelsforexplanationButtonGroup.Title = 'Settings (Hover over labels for explanation.)';
            app.NoiseFilterButtonGroup = uibuttongroup(app.SettingsHoveroverlabelsforexplanationButtonGroup);
            app.NoiseFilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NoiseFilterButtonGroupSelectionChanged, true);
            app.NoiseFilterButtonGroup.Title = 'Noise Filter';
            app.AutoButton = uiradiobutton(app.NoiseFilterButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.ManualButton = uiradiobutton(app.NoiseFilterButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.CellFilterButtonGroup = uibuttongroup(app.SettingsHoveroverlabelsforexplanationButtonGroup);
            app.CellFilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CellFilterButtonGroupSelectionChanged, true);
            app.CellFilterButtonGroup.Title = 'Cell Filter';
            app.MinimumSizeButton = uiradiobutton(app.CellFilterButtonGroup);
            app.MinimumSizeButton.Enable = 'off';
            app.MinimumSizeButton.Text = 'Minimum Size';
            app.MinimumSizeButton.Value = true;
            app.NeuronCountButton = uiradiobutton(app.CellFilterButtonGroup);
            app.NeuronCountButton.Enable = 'off';
            app.NeuronCountButton.Text = 'Neuron Count';
            app.DisplayButtonGroup = uibuttongroup(app.SettingsHoveroverlabelsforexplanationButtonGroup);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.OriginalButton = uiradiobutton(app.DisplayButtonGroup);
            app.OriginalButton.Enable = 'off';
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.AutoSegButton = uiradiobutton(app.DisplayButtonGroup);
            app.AutoSegButton.Enable = 'off';
            app.AutoSegButton.Text = 'Auto Seg';
            app.MeasureNoiseButton = uibutton(app.SettingsHoveroverlabelsforexplanationButtonGroup, 'push');
            app.MeasureNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureNoiseButtonPushed, true);
            app.MeasureNoiseButton.Text = 'Measure Noise';
            app.BlackOutToolButton_3 = uibutton(app.SettingsHoveroverlabelsforexplanationButtonGroup, 'push');
            app.BlackOutToolButton_3.ButtonPushedFcn = createCallbackFcn(app, @BlackOutToolButton_3Pushed, true);
            app.BlackOutToolButton_3.Text = 'Black Out Tool';
            app.AdjHistogramButton = uibutton(app.SettingsHoveroverlabelsforexplanationButtonGroup, 'push');
            app.AdjHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @AdjHistogramButtonPushed, true);
            app.AdjHistogramButton.Text = 'Adj. Histogram';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.GENERATEButton = uibutton(app.ButtonGroup_2, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Text = 'GENERATE';
            app.RESETButton = uibutton(app.ButtonGroup_2, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.Label = uilabel(app.ButtonGroup);
            app.Label.Text = '';
            app.AveragenoncellintensityEditFieldLabel = uilabel(app.ButtonGroup);
            app.AveragenoncellintensityEditFieldLabel.Text = 'Average non-cell intensity:';
            app.AveragenoncellintensityEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.AveragenoncellintensityEditField.Editable = 'off';
            app.AveragenoncellintensityEditField.Tooltip = {'Averaged non-cell region intensity, which helps the program to ignore the background noises.'};
            app.MinimumSizeLabel = uilabel(app.ButtonGroup);
            app.MinimumSizeLabel.Text = 'Voxel Resolution:';
            app.VoxelResolutionEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.VoxelResolutionEditField.Editable = 'off';
            app.VoxelResolutionEditField.Tooltip = {'Resolution (um/voxels) ratio between z (depth) and x-y plane.'};
            app.EditFieldLabel = uilabel(app.ButtonGroup);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField.Tooltip = {'Depending on cell filter, either the minimum cell size or the expected number of neurons. Used to help the program ignore small non-cell regions.'};
            app.CellFilterLabel = uilabel(app.ButtonGroup);
            app.CellFilterLabel.Text = 'Minimum Size:';
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.TabGroup2 = uitabgroup(app.InformationPanel);
            app.VolumesTab = uitab(app.TabGroup2);
            app.VolumesTab.Title = 'Volumes';
            app.Panel_3 = uipanel(app.VolumesTab);
            app.zSlicesLabel = uilabel(app.Panel_3);
            app.zSlicesLabel.Text = 'z-Slices';
            app.zSlicesListBox = uilistbox(app.VolumesTab);
            app.zSlicesListBox.Multiselect = 'on';
            app.zSlicesListBox.ValueChangedFcn = createCallbackFcn(app, @zSlicesListBoxValueChanged, true);
            app.zSlicesListBox.Tooltip = {'Click on single z-slice to preview or edit. Select multiple to limit segmentation to selection.'};
            app.zSlicesListBox.ClickedFcn = createCallbackFcn(app, @zSlicesListBoxClicked, true);
            app.zSlicesListBox.Value = {'Item 1'};
            app.Panel_2 = uipanel(app.VolumesTab);
            app.MaxProjectionButton = uibutton(app.Panel_2, 'state');
            app.MaxProjectionButton.ValueChangedFcn = createCallbackFcn(app, @MaxProjectionButtonValueChanged, true);
            app.MaxProjectionButton.Text = 'Max Projection';
            app.MaxProjectionButton.Value = true;
            app.Panel_4 = uipanel(app.Panel_2);
            app.OpenDataFolderButton = uibutton(app.Panel_4, 'push');
            app.OpenDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataFolderButtonPushed, true);
            app.OpenDataFolderButton.Text = 'Open Data Folder';
            app.OpenSegmentationFolderButton_2 = uibutton(app.Panel_4, 'push');
            app.OpenSegmentationFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenSegmentationFolderButton_2Pushed, true);
            app.OpenSegmentationFolderButton_2.Text = 'Open Segmentation Folder';
            app.Panel_5 = uipanel(app.VolumesTab);
            app.ColorChannelsLabel = uilabel(app.Panel_5);
            app.ColorChannelsLabel.Text = 'Color Channels';
            app.Panel_6 = uipanel(app.Panel_5);
            app.RedCheckBox = uicheckbox(app.Panel_6);
            app.RedCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedCheckBoxValueChanged, true);
            app.RedCheckBox.Text = 'Red';
            app.RedCheckBox.Value = true;
            app.GreenCheckBox = uicheckbox(app.Panel_6);
            app.GreenCheckBox.ValueChangedFcn = createCallbackFcn(app, @GreenCheckBoxValueChanged, true);
            app.GreenCheckBox.Text = 'Green';
            app.GreenCheckBox.Value = true;
            app.BlueCheckBox = uicheckbox(app.Panel_6);
            app.BlueCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlueCheckBoxValueChanged, true);
            app.BlueCheckBox.Text = 'Blue';
            app.BlueCheckBox.Value = true;
            app.WhiteCheckBox = uicheckbox(app.Panel_6);
            app.WhiteCheckBox.ValueChangedFcn = createCallbackFcn(app, @WhiteCheckBoxValueChanged, true);
            app.WhiteCheckBox.Text = 'White';
            app.GFPCheckBox = uicheckbox(app.Panel_6);
            app.GFPCheckBox.ValueChangedFcn = createCallbackFcn(app, @GFPCheckBoxValueChanged, true);
            app.GFPCheckBox.Text = 'GFP';
            app.LysoAutofluCheckBox = uicheckbox(app.Panel_6);
            app.LysoAutofluCheckBox.Text = 'Lyso Autoflu...';
            app.GutAutofluoCheckBox = uicheckbox(app.Panel_6);
            app.GutAutofluoCheckBox.Text = 'Gut Autofluo...';
            app.NeuronsTab = uitab(app.TabGroup2);
            app.NeuronsTab.Title = 'Neurons';
            app.UITable = uitable(app.NeuronsTab);
            app.UITable.ColumnName = {'Neuron'; 'Suspected ID'; 'Confidence (%)'; 'Coords'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = true;
            app.UITable.Multiselect = 'off';
            app.SegmentedneuronssuccesfullyIDd75neuronsLabel = uilabel(app.NeuronsTab);
            app.SegmentedneuronssuccesfullyIDd75neuronsLabel.Text = 'Segmented              neurons, succesfully ID''d (75%+)              neurons.';
            app.IdCount = uieditfield(app.NeuronsTab, 'numeric');
            app.IdCount.Editable = 'off';
            app.SegCount = uieditfield(app.NeuronsTab, 'numeric');
            app.SegCount.Editable = 'off';
            app.ExportButton = uibutton(app.NeuronsTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.PasstoMainButton = uibutton(app.NeuronsTab, 'push');
            app.PasstoMainButton.ButtonPushedFcn = createCallbackFcn(app, @PasstoMainButtonPushed, true);
            app.PasstoMainButton.Text = 'Pass to Main';
            app.CreditTab = uitab(app.TabGroup2);
            app.CreditTab.Title = 'Credit';
            app.TextArea = uitextarea(app.CreditTab);
            app.TextArea.Value = {'@article{'; 'author = {Wen, Chentao and Miura, Takuya and Voleti, Venkatakaushik and Yamaguchi, Kazushi and Tsutsumi, Motosuke and Yamamoto, Kei and Otomo, Kohei and Fujie, Yukako and Teramoto, Takayuki and Ishihara, Takeshi and Aoki, Kazuhiro and Nemoto, Tomomi and Hillman, Elizabeth MC and Kimura, Koutarou D},'; 'doi = {10.7554/eLife.59187},'; 'journal = {eLife},'; 'month = {mar},'; 'title = {{3DeeCellTracker, a deep learning-based pipeline for segmenting and tracking cells in 3D time lapse images}},'; 'volume = {10},'; 'year = {2021}'; '}'};
            app.Segmentationrelieson3DeeCellTrackeraLabel = uilabel(app.CreditTab);
            app.Segmentationrelieson3DeeCellTrackeraLabel.Text = 'Segmentation relies on a modified version of 3DeeCellTracker, a deep-learning based pipeline for tracking cells in 3D time lapse images of deforming/moving organs developed by Chentao Wen et al.';
            app.CiteassuchLabel = uilabel(app.CreditTab);
            app.CiteassuchLabel.Text = 'Cite as such:';
            app.OriginalPaperButton = uibutton(app.CreditTab, 'push');
            app.OriginalPaperButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalPaperButtonPushed, true);
            app.OriginalPaperButton.Text = 'Original Paper';
            app.GithubRepositoryButton = uibutton(app.CreditTab, 'push');
            app.GithubRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @GithubRepositoryButtonPushed, true);
            app.GithubRepositoryButton.Text = 'Github Repository';
            app.LaboratoryPageButton = uibutton(app.CreditTab, 'push');
            app.LaboratoryPageButton.ButtonPushedFcn = createCallbackFcn(app, @LaboratoryPageButtonPushed, true);
            app.LaboratoryPageButton.Text = 'Laboratory Page';
            app.PreviewImageLabel = uilabel(app.UIFigure);
            app.PreviewImageLabel.Text = 'Preview Image';
            app.Panel_7 = uipanel(app.UIFigure);
            app.Slider = uislider(app.Panel_7);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged2, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging2, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = autoseg_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end