classdef nwbsave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SaveasNWBFileUIFigure           matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        AuthorshipTab                   matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        RelatedPublications             matlab.ui.control.EditField
        RelatedPublicationsLabel        matlab.ui.control.Label
        DatadescriptionTextArea         matlab.ui.control.TextArea
        DatadescriptionTextAreaLabel    matlab.ui.control.Label
        InstitutionalAffiliationEditField  matlab.ui.control.EditField
        InstitutionalAffiliationEditFieldLabel  matlab.ui.control.Label
        AuthorLaboratoryEditField       matlab.ui.control.EditField
        AuthorLaboratoryEditFieldLabel  matlab.ui.control.Label
        MicroscopeTab                   matlab.ui.container.Tab
        GridLayout4_2                   matlab.ui.container.GridLayout
        DeviceManufacturerEditFieldLabel_2  matlab.ui.control.Label
        DeviceUITable                   matlab.ui.control.Table
        Panel_18                        matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        EditButton                      matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        AddHardwareDeviceButton         matlab.ui.control.Button
        HardwareDescriptionTextArea     matlab.ui.control.TextArea
        HardwareDescriptionTextAreaLabel  matlab.ui.control.Label
        ManufacturerEditField           matlab.ui.control.EditField
        ManufacturerEditFieldLabel      matlab.ui.control.Label
        HardwareDeviceEditField         matlab.ui.control.EditField
        HardwareDeviceEditFieldLabel    matlab.ui.control.Label
        ChannelsTab                     matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        Panel_24                        matlab.ui.container.Panel
        GridLayout18_2                  matlab.ui.container.GridLayout
        EmissionLabel_2                 matlab.ui.control.Label
        FilterHighEditField_2           matlab.ui.control.NumericEditField
        FilterHighEditField_2Label      matlab.ui.control.Label
        FilterLowEditField_2            matlab.ui.control.NumericEditField
        FilterLowEditField_2Label       matlab.ui.control.Label
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_9Label                matlab.ui.control.Label
        Panel_23                        matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        ExcitationLabel                 matlab.ui.control.Label
        FilterHighEditField             matlab.ui.control.NumericEditField
        FilterHighEditFieldLabel        matlab.ui.control.Label
        FilterLowEditField              matlab.ui.control.NumericEditField
        FilterLowEditFieldLabel         matlab.ui.control.Label
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_7Label                matlab.ui.control.Label
        Panel_20                        matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        RemoveButton_2                  matlab.ui.control.Button
        EditButton_2                    matlab.ui.control.Button
        AddOpticalChannelButton         matlab.ui.control.Button
        GridLayout6                     matlab.ui.container.GridLayout
        OpticalUITable                  matlab.ui.control.Table
        OpticalChannelReferencesLabel   matlab.ui.control.Label
        FilterEditField                 matlab.ui.control.EditField
        FilterEditFieldLabel            matlab.ui.control.Label
        FluorophoreEditField            matlab.ui.control.EditField
        FluorophoreEditFieldLabel       matlab.ui.control.Label
        WormTab                         matlab.ui.container.Tab
        GridLayout7_2                   matlab.ui.container.GridLayout
        SubjectInfoPanel_2              matlab.ui.container.Panel
        GridLayout8_2                   matlab.ui.container.GridLayout
        SubjectNotesTextArea            matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        BodypartDropDown                matlab.ui.control.DropDown
        BodypartDropDownLabel           matlab.ui.control.Label
        CultivationtempCEditField       matlab.ui.control.NumericEditField
        CultivationtempCEditField_2Label  matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDown_2Label              matlab.ui.control.Label
        AgeDropDown                     matlab.ui.control.DropDown
        AgeDropDown_2Label              matlab.ui.control.Label
        SessionDateDatePicker           matlab.ui.control.DatePicker
        SessionDateDatePicker_2Label    matlab.ui.control.Label
        WormIdentifierEditField         matlab.ui.control.EditField
        WormIdentifierEditFieldLabel    matlab.ui.control.Label
        StrainEditField                 matlab.ui.control.EditField
        StrainEditField_2Label_2        matlab.ui.control.Label
        NotesTab                        matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        NeuroPALVolumeTab               matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        Panel_19                        matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        NpalNotes                       matlab.ui.control.EditField
        NotesLabel                      matlab.ui.control.Label
        HardwareDeviceDropDown          matlab.ui.control.DropDown
        HardwareDeviceDropDownLabel     matlab.ui.control.Label
        SegmentationTab                 matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        Panel_21                        matlab.ui.container.Panel
        GridLayout14_3                  matlab.ui.container.GridLayout
        SegNotes                        matlab.ui.control.EditField
        NotesLabel_4                    matlab.ui.control.Label
        NeuralActivityTab               matlab.ui.container.Tab
        GridLayout17_2                  matlab.ui.container.GridLayout
        Panel_22                        matlab.ui.container.Panel
        GridLayout14_4                  matlab.ui.container.GridLayout
        ActivityNotes                   matlab.ui.control.EditField
        NotesLabel_5                    matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_5                         matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        CustomFileName                  matlab.ui.control.EditField
        CustomFileNameLabel             matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Hyperlink                       matlab.ui.control.Hyperlink
        OpenDANDIaftersavingCheckBox    matlab.ui.control.CheckBox
        NWBCompatibleDataLabel          matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        NeuroPALImageNode               matlab.ui.container.TreeNode
        NeuronIDsNode                   matlab.ui.container.TreeNode
        NeuronSegmentationsNode         matlab.ui.container.TreeNode
    end

    
    properties (Access = public)
        parent_app;
        image_neurons;
        neuron_activity_by_name;
        image_file;
        devices;
        opt_chans;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_neurons, neuron_activity, image_file)
            app.parent_app = parent_app;
            app.image_neurons = image_neurons;
            app.neuron_activity_by_name = neuron_activity;
            app.image_file = image_file;
            GUI_prefs = Program.GUIPreferences.instance();

            if boolean(size(app.image_neurons.neurons,2)>0)
                app.Tree.CheckedNodes(end+1) = app.NeuronIDsNode;
            end

            if ~strcmp(app.parent_app.checkFileExistence(GUI_prefs.seg_path),'False')
                app.Tree.CheckedNodes(end+1) = app.NeuronSegmentationsNode;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            GUI_prefs = Program.GUIPreferences.instance();

            target_file = fullfile(pwd, 'nwb_cache.mat');

            nwb_metadata = struct( ...
                'author', app.AuthorLaboratoryEditField.Value, ...
                'institution', app.InstitutionalAffiliationEditField.Value, ...
                'description', app.DatadescriptionTextArea.Value, ...
                'publications', app.RelatedPublications.Value, ...
                'devices', struct('device_table', app.DeviceUITable.Data), ...
                'fluorophores', struct('fluorophore_table', app.OpticalUITable.Data), ...
                'activity', app.neuron_activity_by_name, ...
                'subj_id', app.WormIdentifierEditField.Value, ...
                'subj_age', app.AgeDropDown.Value, ...
                'subj_sex', app.SexDropDown.Value, ...
                'subj_location', app.BodypartDropDown.Value, ...
                'subj_strain', app.StrainEditField.Value, ...
                'subj_date', posixtime(app.SessionDateDatePicker.Value), ...
                'subj_temp', app.CultivationtempCEditField.Value, ...
                'subj_notes', app.SubjectNotesTextArea.Value, ...
                'npal_device', app.HardwareDeviceDropDown.Value, ...
                'npal_notes', app.NpalNotes.Value, ...
                'segmentation_notes', app.SegNotes.Value, ...
                'activity_notes', app.ActivityNotes.Value, ...
                'custom_file_name', app.CustomFileName.Value, ...
                'image_path', GUI_prefs.image_dir, ...
                'mat_file', app.image_file, ...
                'has_ids', string(boolean(size(app.image_neurons.neurons,2)>0)), ...
                'has_csv', app.parent_app.checkFileExistence(fullfile(GUI_prefs.image_dir,'data.csv')), ...
                'has_gcamp', 0, ...
                'has_annotations', 0, ...
                'has_segmentations', app.parent_app.checkFileExistence(GUI_prefs.seg_path), ...
                'has_activity', 0);

            save(target_file, 'nwb_metadata');

            if isdeployed
                if ispc
                    system(fullfile(pwd, sprintf('\lib\bin\windows\mat2nwb.exe --cache=%s', target_file)))
                elseif ismac
                    ctfroot_path = ctfroot;
                    for i = 1:4
                        ctfroot_path = fileparts(ctfroot_path);
                    end
                    system(fullfile(ctfroot_path, sprintf('lib/bin/macos/mat2nwb --cache=%s', target_file)))
                end
            else
                fullfile(pwd, sprintf('+Wrapper/mat2nwb.py --cache=%s', target_file))
                pyrunfile(fullfile(pwd, sprintf('+Wrapper/mat2nwb.py --cache=%s', target_file)))
            end

            % Options
            if app.OpenDANDIaftersavingCheckBox.Value
                web('https://www.dandiarchive.org/');
            end

            %delete(target_file)
            %delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            nwb_data = [];
            nwb_data.proceed = 0;
            app.parent_app.process_nwb_data(nwb_data);
            delete(app)
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if ~app.CheckBox.Value
                set(app.CustomFileNameLabel,'Visible','on');
                set(app.CustomFileName,'Visible','off');
            else
                set(app.CustomFileNameLabel,'Visible','off');
                set(app.CustomFileName,'Visible','on');
            end
        end

        % Callback function
        function ButtonPushed(app, event)
            app.OpticalUITable.Data(end+1) = [app.F1,app.F2,app.F3];
        end

        % Button pushed function: AddHardwareDeviceButton
        function AddHardwareDeviceButtonPushed(app, event)
            current_devices = app.DeviceUITable.Data;
            new_devices = {char(app.HardwareDeviceEditField.Value), (app.ManufacturerEditField.Value), char(app.HardwareDescriptionTextArea.Value)};
            new_devices = [current_devices; new_devices];

            app.DeviceUITable.Data = new_devices;

            app.HardwareDeviceDropDown.Items{end+1} = app.HardwareDeviceEditField.Value;
            app.HardwareDeviceDropDown.ItemsData{end+1} = app.HardwareDeviceEditField.Value;
            
            app.HardwareDeviceEditField.Value = '';
            app.ManufacturerEditField.Value = '';
            app.HardwareDescriptionTextArea.Value = '';
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            app.HardwareDeviceEditField.Value = char(app.DeviceUITable.Data(app.DeviceUITable.Selection,1));
            app.ManufacturerEditField.Value = char(app.DeviceUITable.Data(app.DeviceUITable.Selection,2));
            app.HardwareDescriptionTextArea.Value = char(app.DeviceUITable.Data(app.DeviceUITable.Selection,3));

            app.HardwareDeviceDropDown.Items(app.DeviceUITable.Selection)
            app.HardwareDeviceDropDown.Items(app.DeviceUITable.Selection) = [];
            app.HardwareDeviceDropDown.ItemsData(app.DeviceUITable.Selection) = [];
            app.DeviceUITable.Data(app.DeviceUITable.Selection, :) = [];
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.HardwareDeviceDropDown.Items(app.DeviceUITable.Selection)
            app.HardwareDeviceDropDown.Items(app.DeviceUITable.Selection) = [];
            app.HardwareDeviceDropDown.ItemsData(app.DeviceUITable.Selection) = [];
            app.DeviceUITable.Data(app.DeviceUITable.Selection, :) = [];
        end

        % Button pushed function: AddOpticalChannelButton
        function AddOpticalChannelButtonPushed(app, event)
            %new_opt = {app.FluorophoreEditField.Value, app.FilterEditField.Value, app.WavelengthTextArea.Value};

            current_channels = app.OpticalUITable.Data;
            new_channels = {char(app.FluorophoreEditField.Value), char(app.FilterEditField.Value), char(app.WavelengthTextArea.Value)};
            new_channels = [current_channels; new_channels];

            app.OpticalUITable.Data = new_channels;
            
            app.FluorophoreEditField.Value = '';
            app.FilterEditField.Value = '';
            app.WavelengthTextArea.Value = '';
        end

        % Button pushed function: EditButton_2
        function EditButton_2Pushed(app, event)
            app.FluorophoreEditField.Value = char(app.OpticalUITable.Data(app.OpticalUITable.Selection,1));
            app.FilterEditField.Value = char(app.OpticalUITable.Data(app.OpticalUITable.Selection,2));
            app.WavelengthTextArea.Value = char(app.OpticalUITable.Data(app.OpticalUITable.Selection,3));

            app.OpticalUITable.Data(app.OpticalUITable.Selection,:) = [];
            app.HardwareDeviceDropDown.ItemsData(app.OpticalUITable.Selection) = [];
            app.HardwareDeviceDropDown.Items(app.OpticalUITable.Selection) = [];
        end

        % Button pushed function: RemoveButton_2
        function RemoveButton_2Pushed(app, event)
            app.OpticalUITable.Data(app.OpticalUITable.Selection,:) = [];
            app.HardwareDeviceDropDown.ItemsData(app.OpticalUITable.Selection) = [];
            app.HardwareDeviceDropDown.Items(app.OpticalUITable.Selection) = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SaveasNWBFileUIFigure = uifigure('Visible', 'off');
            app.SaveasNWBFileUIFigure.Name = 'Save as NWB File';
            app.GridLayout = uigridlayout(app.SaveasNWBFileUIFigure);
            app.Tree = uitree(app.GridLayout, 'checkbox');
            app.Tree.Enable = 'off';
            app.NeuroPALImageNode = uitreenode(app.Tree);
            app.NeuroPALImageNode.Text = 'NeuroPAL Image';
            app.NeuronIDsNode = uitreenode(app.Tree);
            app.NeuronIDsNode.Text = 'Neuron IDs';
            app.NeuronSegmentationsNode = uitreenode(app.Tree);
            app.NeuronSegmentationsNode.Text = 'Neuron Segmentations';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.NeuroPALImageNode];
            app.NWBCompatibleDataLabel = uilabel(app.GridLayout);
            app.NWBCompatibleDataLabel.Text = 'NWB-Compatible Data';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.OpenDANDIaftersavingCheckBox = uicheckbox(app.GridLayout3);
            app.OpenDANDIaftersavingCheckBox.Text = 'Open DANDI after saving.';
            app.Hyperlink = uihyperlink(app.GridLayout3);
            app.Hyperlink.URL = 'https://www.dandiarchive.org/';
            app.Hyperlink.Text = '(?)';
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_5 = uipanel(app.GridLayout2);
            app.GridLayout3_2 = uigridlayout(app.Panel_5);
            app.CheckBox = uicheckbox(app.GridLayout3_2);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CustomFileNameLabel = uilabel(app.GridLayout3_2);
            app.CustomFileNameLabel.Text = ' Custom File Name.';
            app.CustomFileName = uieditfield(app.GridLayout3_2, 'text');
            app.CustomFileName.Visible = 'off';
            app.CustomFileName.Placeholder = 'wormyworm.nwb';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AuthorshipTab = uitab(app.TabGroup);
            app.AuthorshipTab.Title = 'Authorship';
            app.GridLayout4 = uigridlayout(app.AuthorshipTab);
            app.AuthorLaboratoryEditFieldLabel = uilabel(app.GridLayout4);
            app.AuthorLaboratoryEditFieldLabel.Text = 'Author / Laboratory';
            app.AuthorLaboratoryEditField = uieditfield(app.GridLayout4, 'text');
            app.AuthorLaboratoryEditField.Placeholder = '(e.g. "Lorem Ipsum Labs")';
            app.InstitutionalAffiliationEditFieldLabel = uilabel(app.GridLayout4);
            app.InstitutionalAffiliationEditFieldLabel.Text = 'Institutional Affiliation';
            app.InstitutionalAffiliationEditField = uieditfield(app.GridLayout4, 'text');
            app.InstitutionalAffiliationEditField.Placeholder = '(e.g. "Sample University")';
            app.DatadescriptionTextAreaLabel = uilabel(app.GridLayout4);
            app.DatadescriptionTextAreaLabel.Text = 'Data description';
            app.DatadescriptionTextArea = uitextarea(app.GridLayout4);
            app.DatadescriptionTextArea.Placeholder = '(e.g. description of experiment, notes, etc.)';
            app.RelatedPublicationsLabel = uilabel(app.GridLayout4);
            app.RelatedPublicationsLabel.Text = 'Related Publications';
            app.RelatedPublications = uieditfield(app.GridLayout4, 'text');
            app.RelatedPublications.Placeholder = '(e.g. a DOI, a link, etc.)';
            app.MicroscopeTab = uitab(app.TabGroup);
            app.MicroscopeTab.Title = 'Microscope';
            app.GridLayout4_2 = uigridlayout(app.MicroscopeTab);
            app.HardwareDeviceEditFieldLabel = uilabel(app.GridLayout4_2);
            app.HardwareDeviceEditFieldLabel.Text = 'Hardware Device';
            app.HardwareDeviceEditField = uieditfield(app.GridLayout4_2, 'text');
            app.HardwareDeviceEditField.Placeholder = '(e.g. "Spinning Disk Confocal")';
            app.ManufacturerEditFieldLabel = uilabel(app.GridLayout4_2);
            app.ManufacturerEditFieldLabel.Text = 'Manufacturer';
            app.ManufacturerEditField = uieditfield(app.GridLayout4_2, 'text');
            app.ManufacturerEditField.Placeholder = '(e.g. Nikon)';
            app.HardwareDescriptionTextAreaLabel = uilabel(app.GridLayout4_2);
            app.HardwareDescriptionTextAreaLabel.Text = 'Hardware Description';
            app.HardwareDescriptionTextArea = uitextarea(app.GridLayout4_2);
            app.HardwareDescriptionTextArea.Placeholder = '(e.g. Ti-e 60x Objective, 1.2 NA Nikon CFI Plan Apochromat VC 60XC WI)';
            app.AddHardwareDeviceButton = uibutton(app.GridLayout4_2, 'push');
            app.AddHardwareDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @AddHardwareDeviceButtonPushed, true);
            app.AddHardwareDeviceButton.Text = 'Add Hardware Device';
            app.Panel_18 = uipanel(app.GridLayout4_2);
            app.GridLayout13 = uigridlayout(app.Panel_18);
            app.RemoveButton = uibutton(app.GridLayout13, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.EditButton = uibutton(app.GridLayout13, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DeviceUITable = uitable(app.GridLayout4_2);
            app.DeviceUITable.ColumnName = {'Name'; 'Manufacturer'; 'Description'};
            app.DeviceUITable.RowName = {};
            app.DeviceUITable.SelectionType = 'row';
            app.DeviceUITable.ColumnEditable = true;
            app.DeviceManufacturerEditFieldLabel_2 = uilabel(app.GridLayout4_2);
            app.DeviceManufacturerEditFieldLabel_2.Text = 'Hardware Device List (e.g. Microscope, Camera, etc)';
            app.ChannelsTab = uitab(app.TabGroup);
            app.ChannelsTab.Title = 'Channels';
            app.GridLayout15 = uigridlayout(app.ChannelsTab);
            app.FluorophoreEditFieldLabel = uilabel(app.GridLayout15);
            app.FluorophoreEditFieldLabel.Text = 'Fluorophore';
            app.FluorophoreEditField = uieditfield(app.GridLayout15, 'text');
            app.FluorophoreEditField.Placeholder = '(e.g. "mTagBFP2")';
            app.FilterEditFieldLabel = uilabel(app.GridLayout15);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.FilterEditField = uieditfield(app.GridLayout15, 'text');
            app.FilterEditField.Placeholder = '(e.g. "Semrock FF01-445/45-25 Brightline")';
            app.GridLayout6 = uigridlayout(app.GridLayout15);
            app.OpticalChannelReferencesLabel = uilabel(app.GridLayout6);
            app.OpticalChannelReferencesLabel.Text = 'Optical Channel References';
            app.OpticalUITable = uitable(app.GridLayout6);
            app.OpticalUITable.ColumnName = {'Protein'; 'Filter'; 'Ex. λ'; 'Ex. Low'; 'Ex. High'; 'Em. λ'; 'Em. Low'; 'Em. High'};
            app.OpticalUITable.RowName = {};
            app.OpticalUITable.SelectionType = 'row';
            app.OpticalUITable.ColumnEditable = true;
            app.AddOpticalChannelButton = uibutton(app.GridLayout15, 'push');
            app.AddOpticalChannelButton.ButtonPushedFcn = createCallbackFcn(app, @AddOpticalChannelButtonPushed, true);
            app.AddOpticalChannelButton.Text = 'Add Optical Channel';
            app.Panel_20 = uipanel(app.GridLayout15);
            app.GridLayout16 = uigridlayout(app.Panel_20);
            app.EditButton_2 = uibutton(app.GridLayout16, 'push');
            app.EditButton_2.ButtonPushedFcn = createCallbackFcn(app, @EditButton_2Pushed, true);
            app.EditButton_2.Text = 'Edit';
            app.RemoveButton_2 = uibutton(app.GridLayout16, 'push');
            app.RemoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton_2Pushed, true);
            app.RemoveButton_2.Text = 'Remove';
            app.Panel_23 = uipanel(app.GridLayout15);
            app.GridLayout18 = uigridlayout(app.Panel_23);
            app.EditField_7Label = uilabel(app.GridLayout18);
            app.EditField_7Label.Text = 'λ';
            app.EditField_7 = uieditfield(app.GridLayout18, 'numeric');
            app.FilterLowEditFieldLabel = uilabel(app.GridLayout18);
            app.FilterLowEditFieldLabel.Text = 'Filter Low';
            app.FilterLowEditField = uieditfield(app.GridLayout18, 'numeric');
            app.FilterHighEditFieldLabel = uilabel(app.GridLayout18);
            app.FilterHighEditFieldLabel.Text = 'Filter High';
            app.FilterHighEditField = uieditfield(app.GridLayout18, 'numeric');
            app.ExcitationLabel = uilabel(app.GridLayout18);
            app.ExcitationLabel.Text = 'Excitation';
            app.Panel_24 = uipanel(app.GridLayout15);
            app.GridLayout18_2 = uigridlayout(app.Panel_24);
            app.EditField_9Label = uilabel(app.GridLayout18_2);
            app.EditField_9Label.Text = 'λ';
            app.EditField_9 = uieditfield(app.GridLayout18_2, 'numeric');
            app.FilterLowEditField_2Label = uilabel(app.GridLayout18_2);
            app.FilterLowEditField_2Label.Text = 'Filter Low';
            app.FilterLowEditField_2 = uieditfield(app.GridLayout18_2, 'numeric');
            app.FilterHighEditField_2Label = uilabel(app.GridLayout18_2);
            app.FilterHighEditField_2Label.Text = 'Filter High';
            app.FilterHighEditField_2 = uieditfield(app.GridLayout18_2, 'numeric');
            app.EmissionLabel_2 = uilabel(app.GridLayout18_2);
            app.EmissionLabel_2.Text = 'Emission';
            app.WormTab = uitab(app.TabGroup);
            app.WormTab.Title = 'Worm';
            app.GridLayout7_2 = uigridlayout(app.WormTab);
            app.SubjectInfoPanel_2 = uipanel(app.GridLayout7_2);
            app.SubjectInfoPanel_2.Title = 'Subject Info';
            app.GridLayout8_2 = uigridlayout(app.SubjectInfoPanel_2);
            app.StrainEditField_2Label_2 = uilabel(app.GridLayout8_2);
            app.StrainEditField_2Label_2.Text = 'Strain';
            app.StrainEditField = uieditfield(app.GridLayout8_2, 'text');
            app.WormIdentifierEditFieldLabel = uilabel(app.GridLayout8_2);
            app.WormIdentifierEditFieldLabel.Text = 'Worm Identifier';
            app.WormIdentifierEditField = uieditfield(app.GridLayout8_2, 'text');
            app.WormIdentifierEditField.Placeholder = '0000';
            app.SessionDateDatePicker_2Label = uilabel(app.GridLayout8_2);
            app.SessionDateDatePicker_2Label.Text = 'Session Date';
            app.SessionDateDatePicker = uidatepicker(app.GridLayout8_2);
            app.AgeDropDown_2Label = uilabel(app.GridLayout8_2);
            app.AgeDropDown_2Label.Text = 'Age';
            app.AgeDropDown = uidropdown(app.GridLayout8_2);
            app.AgeDropDown.Items = {'Adult', 'L4', 'L3', 'L2', 'L1', '3-Fold'};
            app.AgeDropDown.Value = 'Adult';
            app.SexDropDown_2Label = uilabel(app.GridLayout8_2);
            app.SexDropDown_2Label.Text = 'Sex';
            app.SexDropDown = uidropdown(app.GridLayout8_2);
            app.SexDropDown.Items = {'XX', 'XO'};
            app.SexDropDown.Value = 'XX';
            app.CultivationtempCEditField_2Label = uilabel(app.GridLayout8_2);
            app.CultivationtempCEditField_2Label.Text = 'Cultivation temp (°C)';
            app.CultivationtempCEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.BodypartDropDownLabel = uilabel(app.GridLayout8_2);
            app.BodypartDropDownLabel.Text = 'Body part';
            app.BodypartDropDown = uidropdown(app.GridLayout8_2);
            app.BodypartDropDown.Items = {'Whole Worm', 'Head', 'Midbody', 'Anterior Midbody', 'Central Midbody', 'Posterior Midbody', 'Tail'};
            app.BodypartDropDown.Value = 'Whole Worm';
            app.NotesTextAreaLabel = uilabel(app.GridLayout8_2);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.SubjectNotesTextArea = uitextarea(app.GridLayout8_2);
            app.NotesTab = uitab(app.TabGroup);
            app.NotesTab.Title = 'Notes';
            app.GridLayout7 = uigridlayout(app.NotesTab);
            app.TabGroup2 = uitabgroup(app.GridLayout7);
            app.NeuroPALVolumeTab = uitab(app.TabGroup2);
            app.NeuroPALVolumeTab.Title = 'NeuroPAL Volume';
            app.GridLayout12 = uigridlayout(app.NeuroPALVolumeTab);
            app.HardwareDeviceDropDownLabel = uilabel(app.GridLayout12);
            app.HardwareDeviceDropDownLabel.Text = 'Hardware Device';
            app.HardwareDeviceDropDown = uidropdown(app.GridLayout12);
            app.HardwareDeviceDropDown.Items = {};
            app.HardwareDeviceDropDown.Placeholder = 'Select Device';
            app.HardwareDeviceDropDown.Value = {};
            app.Panel_19 = uipanel(app.GridLayout12);
            app.GridLayout14 = uigridlayout(app.Panel_19);
            app.NotesLabel = uilabel(app.GridLayout14);
            app.NotesLabel.Text = 'Notes';
            app.NpalNotes = uieditfield(app.GridLayout14, 'text');
            app.NpalNotes.Placeholder = 'Insert notes here...';
            app.SegmentationTab = uitab(app.TabGroup2);
            app.SegmentationTab.Title = 'Segmentation';
            app.GridLayout17 = uigridlayout(app.SegmentationTab);
            app.Panel_21 = uipanel(app.GridLayout17);
            app.GridLayout14_3 = uigridlayout(app.Panel_21);
            app.NotesLabel_4 = uilabel(app.GridLayout14_3);
            app.NotesLabel_4.Text = 'Notes';
            app.SegNotes = uieditfield(app.GridLayout14_3, 'text');
            app.SegNotes.Placeholder = 'Insert notes here...';
            app.NeuralActivityTab = uitab(app.TabGroup2);
            app.NeuralActivityTab.Title = 'Neural Activity';
            app.GridLayout17_2 = uigridlayout(app.NeuralActivityTab);
            app.Panel_22 = uipanel(app.GridLayout17_2);
            app.GridLayout14_4 = uigridlayout(app.Panel_22);
            app.NotesLabel_5 = uilabel(app.GridLayout14_4);
            app.NotesLabel_5.Text = 'Notes';
            app.ActivityNotes = uieditfield(app.GridLayout14_4, 'text');
            app.ActivityNotes.Placeholder = 'Insert notes here...';
            % Show the figure after all components are created
            app.SaveasNWBFileUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nwbsave(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SaveasNWBFileUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SaveasNWBFileUIFigure)
        end
    end
end