classdef rotation_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TransformImageUIFigure  matlab.ui.Figure
        SelectionButtonGroup    matlab.ui.container.ButtonGroup
        ShowguidelinesCheckBox  matlab.ui.control.CheckBox
        ResetButton             matlab.ui.control.Button
        MoveBothButton          matlab.ui.control.RadioButton
        MoveNeuronsButton       matlab.ui.control.RadioButton
        MoveImageButton         matlab.ui.control.RadioButton
        ButtonGroup             matlab.ui.container.ButtonGroup
        MirrorButtonGroup       matlab.ui.container.ButtonGroup
        HorizontallyButton_2    matlab.ui.control.Button
        VerticallyButton_2      matlab.ui.control.Button
        TranformButtonGroup     matlab.ui.container.ButtonGroup
        EditField               matlab.ui.control.NumericEditField
        Rotation                matlab.ui.control.Knob
        RotateButtonGroup_2     matlab.ui.container.ButtonGroup
        AntiClockwiseButton     matlab.ui.control.Button
        ClockwiseButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        parent_app;
        image_data;
        image_view;
        repo_img; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, neuropal_app, image_data, image_view)
            app.parent_app = neuropal_app;
            app.image_data = image_data;
            app.image_view = image_view;
            app.repo_img = image_data;
        end

        % Value changed function: Rotation
        function RotationValueChanged(app, event)
            app.EditField.Value = app.Rotation.Value;
        end

        % Callback function
        function ZoomValueChanged(app, event)
            value = app.Zoom.Value;
            
        end

        % Button pushed function: VerticallyButton_2
        function VerticallyButton_2Pushed(app, event)
            if app.MoveBothButton.Value
                app.parent_app.RotateVertical('inclusive');
            elseif app.MoveImageButton.Value
                app.parent_app.RotateVertical('excl_image');
            elseif app.MoveNeuronsButton.Value
                app.parent_app.RotateVertical('excl_neurons');
            end
        end

        % Button pushed function: HorizontallyButton_2
        function HorizontallyButton_2Pushed(app, event)
            if app.MoveBothButton.Value
                app.parent_app.RotateHorizontal('inclusive');
            elseif app.MoveImageButton.Value
                app.parent_app.RotateHorizontal('excl_image');
            elseif app.MoveNeuronsButton.Value
                app.parent_app.RotateHorizontal('excl_neurons');
            end
        end

        % Button pushed function: ClockwiseButton
        function ClockwiseButtonPushed(app, event)
            if app.MoveBothButton.Value
                app.parent_app.RotateClockwise('inclusive');
            elseif app.MoveImageButton.Value
                app.parent_app.RotateClockwise('excl_image');
            elseif app.MoveNeuronsButton.Value
                app.parent_app.RotateClockwise('excl_neurons');
            end
        end

        % Button pushed function: AntiClockwiseButton
        function AntiClockwiseButtonPushed(app, event)
            if app.MoveBothButton.Value
                app.parent_app.RotateAntiClockwise('inclusive');
            elseif app.MoveImageButton.Value
                app.parent_app.RotateAntiClockwise('excl_image');
            elseif app.MoveNeuronsButton.Value
                app.parent_app.RotateAntiClockwise('excl_neurons');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TransformImageUIFigure = uifigure('Visible', 'off');
            app.TransformImageUIFigure.Name = 'Transform Image';
            app.RotateButtonGroup_2 = uibuttongroup(app.TransformImageUIFigure);
            app.RotateButtonGroup_2.Title = 'Rotate';
            app.ClockwiseButton = uibutton(app.RotateButtonGroup_2, 'push');
            app.ClockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @ClockwiseButtonPushed, true);
            app.ClockwiseButton.Text = 'Clockwise';
            app.AntiClockwiseButton = uibutton(app.RotateButtonGroup_2, 'push');
            app.AntiClockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @AntiClockwiseButtonPushed, true);
            app.AntiClockwiseButton.Text = 'Anti-Clockwise';
            app.TranformButtonGroup = uibuttongroup(app.TransformImageUIFigure);
            app.TranformButtonGroup.Title = 'Tranform';
            app.Rotation = uiknob(app.TranformButtonGroup, 'continuous');
            app.Rotation.Limits = [0 180];
            app.Rotation.ValueChangedFcn = createCallbackFcn(app, @RotationValueChanged, true);
            app.EditField = uieditfield(app.TranformButtonGroup, 'numeric');
            app.MirrorButtonGroup = uibuttongroup(app.TransformImageUIFigure);
            app.MirrorButtonGroup.Title = 'Mirror';
            app.VerticallyButton_2 = uibutton(app.MirrorButtonGroup, 'push');
            app.VerticallyButton_2.ButtonPushedFcn = createCallbackFcn(app, @VerticallyButton_2Pushed, true);
            app.VerticallyButton_2.Text = 'Vertically';
            app.HorizontallyButton_2 = uibutton(app.MirrorButtonGroup, 'push');
            app.HorizontallyButton_2.ButtonPushedFcn = createCallbackFcn(app, @HorizontallyButton_2Pushed, true);
            app.HorizontallyButton_2.Text = 'Horizontally';
            app.SelectionButtonGroup = uibuttongroup(app.TransformImageUIFigure);
            app.SelectionButtonGroup.Title = 'Selection';
            app.ButtonGroup = uibuttongroup(app.SelectionButtonGroup);
            app.MoveImageButton = uiradiobutton(app.SelectionButtonGroup);
            app.MoveImageButton.Text = 'Move Image';
            app.MoveNeuronsButton = uiradiobutton(app.SelectionButtonGroup);
            app.MoveNeuronsButton.Text = 'Move Neurons';
            app.MoveBothButton = uiradiobutton(app.SelectionButtonGroup);
            app.MoveBothButton.Text = 'Move Both';
            app.MoveBothButton.Value = true;
            app.ResetButton = uibutton(app.SelectionButtonGroup, 'push');
            app.ResetButton.Text = 'Reset';
            app.ShowguidelinesCheckBox = uicheckbox(app.SelectionButtonGroup);
            app.ShowguidelinesCheckBox.Enable = 'off';
            app.ShowguidelinesCheckBox.Text = 'Show guidelines';
            % Show the figure after all components are created
            app.TransformImageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rotation_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TransformImageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TransformImageUIFigure)
        end
    end
end